classdef EMG_Comparison_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        NormalizeButton  matlab.ui.control.Button
        StatusLabel      matlab.ui.control.Label
        PlotButton       matlab.ui.control.Button
        LoadFileButton   matlab.ui.control.Button
        UIAxesCorr       matlab.ui.control.UIAxes
        UIAxesNorm       matlab.ui.control.UIAxes
        UIAxesOriginal   matlab.ui.control.UIAxes
    end

    properties (Access = private)
        FilePath        string    % Store selected file path
        UserData         struct    % Store loaded EMG data
        NormalizedData   struct
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            % Open file selection dialog for .mat files
            [file, path] = uigetfile({'*.mat', 'MATLAB Files (*.mat)'}, 'Select EMG Data File');

            if isequal(file, 0)
                app.StatusLabel.Text = 'No file selected.';
                return;
            end

            % Store the file path in the app for later use
            app.FilePath = fullfile(path, file);
            app.StatusLabel.Text = ['Loaded: ', file];

            % Load the user’s .mat file
            data = load(app.FilePath);

            % if ~isfield(data, 'emg_data_struct') || ~isfield(data.emg_data_struct, 'cut_data')
            %     app.StatusLabel.Text = 'Invalid structure in the loaded file.';
            %     return;
            % end

            % Store loaded data in the app
            app.UserData = data;
            app.StatusLabel.Text = 'File loaded successfully.';
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            if isempty(app.NormalizedData)
                app.StatusLabel.Text = 'Please normalize the data first.';
                return;
            end

            % ✅ Load the Reference EMG Data
            reference_file = 'C:\Users\sergi\Documents\CEU\TFG\results\mean\representative_emg_curve.mat';

            if exist(reference_file, 'file') ~= 2
                app.StatusLabel.Text = 'Reference file not found.';
                return;
            end

            reference_data = load(reference_file);
            if ~isfield(reference_data, 'emg_results')
                app.StatusLabel.Text = 'Reference data is invalid.';
                return;
            end
            reference_emg = reference_data.emg_results;

            % ✅ Extract muscles from finalStruct in user data
            if ~isfield(app.NormalizedData, 'finalStruct')
                app.StatusLabel.Text = 'Error: "finalStruct" not found in user data.';
                return;
            end

            user_muscles = fieldnames(app.NormalizedData.finalStruct);
            ref_muscles = setdiff(fieldnames(reference_emg), 'time_vector'); % Ignore time_vector

            % ✅ Loop through user muscles and plot
            for i = 1:length(user_muscles)
                muscle_name = user_muscles{i};

                % ✅ Ensure the reference data contains this muscle
                if ~ismember(muscle_name, ref_muscles)
                    continue;
                end

                % ✅ Extract repetitions for this muscle
                reps = fieldnames(app.NormalizedData.finalStruct.(muscle_name));
                if isempty(reps)
                    continue;
                end

                % ✅ Select the first repetition
                rep_name = reps{1};
                user_signal = app.NormalizedData.finalStruct.(muscle_name).(rep_name);

                % ✅ Extract reference mean and std
                ref_mean = reference_emg.(muscle_name).mean;
                ref_std = reference_emg.(muscle_name).std_dev;
                ref_time = linspace(0, 100, length(ref_mean)); % Time axis

                % ✅ Ensure signals have the same length
                min_length = min(length(user_signal), length(ref_mean));
                user_signal = user_signal(1:min_length);
                ref_mean = ref_mean(1:min_length);
                ref_std = ref_std(1:min_length);
                ref_time = ref_time(1:min_length);

                % ✅ Plot in UIAxesOriginal
                hold(app.UIAxesOriginal, 'on');
                fill(app.UIAxesOriginal, [ref_time, fliplr(ref_time)], ...
                    [ref_mean + ref_std, fliplr(ref_mean - ref_std)], ...
                    'b', 'FaceAlpha', 0.3, 'EdgeColor', 'none'); % Std Dev shading
                plot(app.UIAxesOriginal, ref_time, ref_mean, 'b', 'LineWidth', 2); % Reference mean
                plot(app.UIAxesOriginal, ref_time, user_signal, 'r', 'LineWidth', 2); % User data
                hold(app.UIAxesOriginal, 'off');

                xlabel(app.UIAxesOriginal, '% Reach and Grasp Cycle', 'FontSize', 12);
                ylabel(app.UIAxesOriginal, 'Normalized EMG Activity', 'FontSize', 12);
                title(app.UIAxesOriginal, ['User EMG vs Reference - ', muscle_name], 'FontSize', 14);
                legend(app.UIAxesOriginal, {'± Std Dev', 'Reference Mean', 'User Signal'}, 'Location', 'best', 'FontSize', 10);
                grid(app.UIAxesOriginal, 'on');

                % ✅ Only plot the first muscle
                break;
            end

            % ✅ Update Status Label
            app.StatusLabel.Text = 'Plot generated successfully.';
        end

        % Button pushed function: NormalizeButton
        function NormalizeButtonPushed(app, event)
            if isempty(app.UserData)
                app.StatusLabel.Text = 'Please load a file first.';
                return;
            end

            % Define normalization parameters
            num_points = 1000;
            normalized_data = struct();
            app.StatusLabel.Text = fieldnames(app.UserData);
            reps = fieldnames(app.UserData.finalStruct);

            % Normalize each repetition and muscle
            for r = 1:length(reps)
                rep_name = reps{r};
                muscles = fieldnames(app.UserData);

                for m = 1:length(muscles)
                    muscle_name = muscles{m};
                    envelope_data = app.UserData.(muscle_name).(rep_name);

                    % Check if data is valid
                    if isempty(envelope_data)
                        continue;
                    end

                    % Fill missing data
                    envelope_data = fillmissing(envelope_data, 'linear');

                    % Time normalization
                    original_time = linspace(0, 100, length(envelope_data));
                    normalized_time = linspace(0, 100, num_points);
                    interpolated_envelope = interp1(original_time, envelope_data, normalized_time, 'pchip');

                    % Amplitude normalization
                    min_val = min(interpolated_envelope);
                    max_val = max(interpolated_envelope);
                    if max_val > min_val
                        normalized_envelope = (interpolated_envelope - min_val) / (max_val - min_val);
                    else
                        normalized_envelope = interpolated_envelope;
                    end

                    % Store normalized data
                    normalized_data.(rep_name).(muscle_name).envelope = normalized_envelope;
                end
            end

            % Save the normalized data inside the app
            app.NormalizedData = normalized_data;
            app.StatusLabel.Text = 'Data normalized successfully.';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesOriginal = uiaxes(app.UIFigure);
            title(app.UIAxesOriginal, 'Title')
            xlabel(app.UIAxesOriginal, 'X')
            ylabel(app.UIAxesOriginal, 'Y')
            zlabel(app.UIAxesOriginal, 'Z')
            app.UIAxesNorm = uiaxes(app.UIFigure);
            title(app.UIAxesNorm, 'Title')
            xlabel(app.UIAxesNorm, 'X')
            ylabel(app.UIAxesNorm, 'Y')
            zlabel(app.UIAxesNorm, 'Z')
            app.UIAxesCorr = uiaxes(app.UIFigure);
            title(app.UIAxesCorr, 'Title')
            xlabel(app.UIAxesCorr, 'X')
            ylabel(app.UIAxesCorr, 'Y')
            zlabel(app.UIAxesCorr, 'Z')
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Analyze';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Select a file to start';
            app.NormalizeButton = uibutton(app.UIFigure, 'push');
            app.NormalizeButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizeButtonPushed, true);
            app.NormalizeButton.Text = 'Normalize';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EMG_Comparison_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end